classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        OnlineModelPriceOptimizerLabel  matlab.ui.control.Label
        RunOptimizationButton           matlab.ui.control.StateButton
        SelectGranularityminutesDropDown  matlab.ui.control.DropDown
        SelectGranularityminutesDropDownLabel  matlab.ui.control.Label
        DataFilexlsxEditField           matlab.ui.control.EditField
        LoadDataButton                  matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DataFilexlsxEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        demand 
        cost_para 
        max_pow 
        min_pow 
        max_stor 
        max_pow_cap 
        num_gen 
        eff 
        initial_ener 
        gensource 
        time_input 
        total_rev 
        pow 
        pow_char 
        pow_discharge 
        ener_lev 
        price 
        weight 
        granularity 
        demand_fig
        ener_fig
        price_fig
        genout_fig
    end
    
    properties (Access = private)
        app2 = []; % dialogueapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            filename=app.DataFilexlsxEditField.Value;
            opts = detectImportOptions(filename,'NumHeaderLines',0);
            t=readtable(filename,opts);
            [app.demand,app.cost_para,app.max_pow,app.min_pow,app.max_stor,app.max_pow_cap,app.num_gen,app.eff,app.initial_ener,app.gensource]=getdata(t)
            app.UITable.Data=t;
            app.UITable.ColumnName=t.Properties.VariableNames;
        end

        % Callback function
        function DataFilexlsxEditFieldValueChanged(app, event)

        end

        % Callback function: SelectGranularityminutesDropDown, 
        % ...and 1 other component
        function SelectGranularityminutesDropDownValueChanged(app, event)

        end

        % Value changed function: RunOptimizationButton
        function RunOptimizationButtonValueChanged(app, event)
             app.time_input = str2double(app.SelectGranularityminutesDropDown.Value);
            [app.total_rev,app.pow,app.pow_char,app.pow_discharge,app.ener_lev,app.price,app.weight,app.granularity]=periodonlineoptimize(app.time_input,app.demand,app.cost_para,app.max_pow,app.min_pow,app.max_stor,app.max_pow_cap,app.eff,app.initial_ener);
            app.app2 = outputapp(app, app.total_rev, app.demand, app.granularity, app.ener_lev, app.pow_char, app.pow_discharge, app.pow, app.max_stor, app.price, app.cost_para, app.gensource); 
            %app.outputappin = outputapp(app, app.total_rev, app.demand, app.granularity, app.ener_lev, app.pow_char, app.pow_discharge, app.pow, app.max_stor, app.price, app.cost_para, app.gensource)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataFilexlsxEditFieldLabel = uilabel(app.UIFigure);
            app.DataFilexlsxEditFieldLabel.Text = 'Data File (xlsx)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time'; 'Demand'; 'Generation Source'; 'Cost Parameter'; 'Maximum Power'; 'Minimum Power'; 'Maximum Storage Capacity'; 'Storage Power Capacity'; 'Efficiency'; 'Initial Storage'};
            app.UITable.RowName = {};
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DataFilexlsxEditField = uieditfield(app.UIFigure, 'text');
            app.SelectGranularityminutesDropDownLabel = uilabel(app.UIFigure);
            app.SelectGranularityminutesDropDownLabel.Text = 'Select Granularity (minutes)';
            app.SelectGranularityminutesDropDown = uidropdown(app.UIFigure);
            app.SelectGranularityminutesDropDown.Items = {'5', '15', '30', '60'};
            app.SelectGranularityminutesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectGranularityminutesDropDownValueChanged, true);
            app.SelectGranularityminutesDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectGranularityminutesDropDownValueChanged, true);
            app.SelectGranularityminutesDropDown.Value = '60';
            app.RunOptimizationButton = uibutton(app.UIFigure, 'state');
            app.RunOptimizationButton.ValueChangedFcn = createCallbackFcn(app, @RunOptimizationButtonValueChanged, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.OnlineModelPriceOptimizerLabel = uilabel(app.UIFigure);
            app.OnlineModelPriceOptimizerLabel.Text = 'Online Model Price Optimizer';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Data Requirements:'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Please ensure data for 24 hours with 5 minute resolution is available in the loaded file. '; 'If variables for given resolution missing, fill input data with ''0'' with the following steps for data granularity:'; 'step=[1,3,6,12] with granularity=[5,15,30,60] respectively.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end