classdef inputapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RealtimeLMPCalculatorLabel   matlab.ui.control.Label
        OptimalLMPUSDEditField       matlab.ui.control.NumericEditField
        OptimalLMPUSDEditFieldLabel  matlab.ui.control.Label
        CurrentDemandEditField       matlab.ui.control.NumericEditField
        CurrentDemandEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        StorageEfficiencyEditField   matlab.ui.control.NumericEditField
        StorageEfficiencyEditFieldLabel  matlab.ui.control.Label
        StoragePowerEditField        matlab.ui.control.NumericEditField
        StoragePowerEditFieldLabel   matlab.ui.control.Label
        CurrentStorageCapacityEditField  matlab.ui.control.NumericEditField
        CurrentStorageCapacityEditFieldLabel  matlab.ui.control.Label
        MaximumStorageCapacityEditField  matlab.ui.control.NumericEditField
        MaximumStorageCapacityEditFieldLabel  matlab.ui.control.Label
        GranularityMinutesDropDownLabel  matlab.ui.control.Label
        GranularityMinutesDropDown   matlab.ui.control.DropDown
        OptimizeButton               matlab.ui.control.StateButton
    end


    properties (Access = private)
        outputapp % callingapp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function DataFilexlsxEditFieldValueChanged(app, event)
            value = app.DataFilexlsxEditField.Value;
        end

        % Callback function: GranularityMinutesDropDown, 
        % ...and 2 other components
        function GranularitySliderValueChanged(app, event)
            %granularity = app.GranularitySlider.Value;
        end

        % Value changed function: OptimizeButton
        function DataFilexlsxEditFieldValueChanged2(app, event)
            filename=app.DataFilexlsxEditField.Value;
            %opts = detectImportOptions(filename,'NumHeaderLines',0);
            data = readtable(filename);
            %data=app.UITable;
           [demand,cost_para,max_pow,min_pow,max_stor,max_pow_cap,num_gen,eff,initial_ener,text]=getdata(data);
        end

        % Callback function
        function RunSimulationButtonValueChanged(app, event)
            [total_rev,pow,pow_char,pow_discharge,ener_lev,pi,v]=periodonlineoptimize(granularity,demand,cost_para,max_pow,min_pow,max_stor,max_pow_cap,num_gen,eff,initial_ener)
        end

        % Callback function
        function GranularityMinutesDropDownOpening(app, event)
            granularity=GranularityMinutesDropDownOpening.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimizeButton = uibutton(app.UIFigure, 'state');
            app.OptimizeButton.ValueChangedFcn = createCallbackFcn(app, @DataFilexlsxEditFieldValueChanged2, true);
            app.OptimizeButton.Text = 'Optimize';
            app.GranularityMinutesDropDown = uidropdown(app.UIFigure);
            app.GranularityMinutesDropDown.Items = {'5', '15', '30', '60'};
            app.GranularityMinutesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GranularitySliderValueChanged, true);
            app.GranularityMinutesDropDown.ValueChangedFcn = createCallbackFcn(app, @GranularitySliderValueChanged, true);
            app.GranularityMinutesDropDown.ClickedFcn = createCallbackFcn(app, @GranularitySliderValueChanged, true);
            app.GranularityMinutesDropDown.Value = '5';
            app.GranularityMinutesDropDownLabel = uilabel(app.UIFigure);
            app.GranularityMinutesDropDownLabel.Text = 'Granularity (Minutes)';
            app.MaximumStorageCapacityEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumStorageCapacityEditFieldLabel.Text = 'Maximum Storage Capacity';
            app.MaximumStorageCapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentStorageCapacityEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentStorageCapacityEditFieldLabel.Text = 'Current Storage Capacity';
            app.CurrentStorageCapacityEditField = uieditfield(app.UIFigure, 'numeric');
            app.StoragePowerEditFieldLabel = uilabel(app.UIFigure);
            app.StoragePowerEditFieldLabel.Text = 'Storage Power';
            app.StoragePowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.StorageEfficiencyEditFieldLabel = uilabel(app.UIFigure);
            app.StorageEfficiencyEditFieldLabel.Text = 'Storage Efficiency';
            app.StorageEfficiencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Generation Source'; 'Cost Parameter (USD)'; 'Maximum Power (MW)'; 'Minimum Power (MW)'};
            app.UITable.RowName = {};
            app.CurrentDemandEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentDemandEditFieldLabel.Text = 'Current Demand';
            app.CurrentDemandEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalLMPUSDEditFieldLabel = uilabel(app.UIFigure);
            app.OptimalLMPUSDEditFieldLabel.Text = 'Optimal LMP (USD)';
            app.OptimalLMPUSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalLMPUSDEditField.Editable = 'off';
            app.RealtimeLMPCalculatorLabel = uilabel(app.UIFigure);
            app.RealtimeLMPCalculatorLabel.Text = 'Real-time LMP Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inputapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end