classdef outputapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TotalOutputRevenueEditField  matlab.ui.control.NumericEditField
        TotalOutputRevenueEditFieldLabel  matlab.ui.control.Label
        ModelResultsLabel            matlab.ui.control.Label
        EnerLevFig                   matlab.ui.control.UIAxes
        PriceFig                     matlab.ui.control.UIAxes
        GenOutputFig                 matlab.ui.control.UIAxes
        DemandFig                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Main App
        demand
        time
        max_cost_para
    end
    
    properties (Access = private)
        app1=[]; % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app1, totalrev, demand, granularity, ener_lev, pow_char, pow_discharge, pow, max_stor, price, cost_para, gensource)
             app.app1 = app1;
             app.TotalOutputRevenueEditField.Value=double(app1.total_rev);
             if app1.granularity==double(1/12)
                 step=1;
             elseif app1.granularity==double(1/4)
                 step=3;
             elseif app1.granularity==double(1/2)
                 step=6;
             elseif app1.granularity==double(1)
                 step=12;
             end
             app.demand=app1.demand(1:step:end)
             app.DemandFig.XTick=0:4:24
             app.DemandFig.YTick=0:1000:max(app.demand)
             plot(app.DemandFig,0:app1.granularity:(24-app1.granularity),app.demand)

             app.PriceFig.XTick=0:4:24
             app.max_cost_para=max(app1.cost_para);
             app.PriceFig.YLim=[0,app.max_cost_para];
             app.PriceFig.YTickMode='auto'
             plot(app.PriceFig,0:app1.granularity:(24-app1.granularity),app1.price)

             app.EnerLevFig.XTick=0:4:24
             hold (app.EnerLevFig, 'on');
             plot(app.EnerLevFig, 0:app1.granularity:24, app1.ener_lev, 'b');
             plot(app.EnerLevFig, 0:app1.granularity:(24-app1.granularity), app1.pow_char,'--g');
             plot(app.EnerLevFig, 0:app1.granularity:(24-app1.granularity), app1.pow_discharge,'--r');
             legend (app.EnerLevFig, 'Energy Level','Charged Power','Discharged Power')
             app.PriceFig.YTickMode='auto'

             app.GenOutputFig.XTick=0:4:24
             hold (app.GenOutputFig,'on');
             for i=1:numel(app1.gensource)
                 plot(app.GenOutputFig,0:app1.granularity:(24-app1.granularity),app1.pow(i,:));
             end
             legend (app.GenOutputFig, app1.gensource)
             app.GenOutputFig.YTickMode='auto'
            %[app.DemandFig, app.PriceFig, app.GenOutputFig, app.EnerLevFig]=outputfigures(caller.demand,caller.granularity,caller.ener_lev,caller.pow_char,caller.pow_discharge,caller.pow,caller.max_stor,caller.price,caller.cost_para,caller.gensource)
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Callback function
        function DemandFigButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DemandFig = uiaxes(app.UIFigure);
            title(app.DemandFig, 'Demand')
            xlabel(app.DemandFig, 'Time (h)')
            ylabel(app.DemandFig, 'Demand (Mwh)')
            zlabel(app.DemandFig, 'Z')
            app.DemandFig.XLimitMethod = 'padded';
            app.DemandFig.XTick = [];
            app.DemandFig.YTick = [];
            app.GenOutputFig = uiaxes(app.UIFigure);
            title(app.GenOutputFig, 'Generator Output')
            xlabel(app.GenOutputFig, 'Time (h)')
            ylabel(app.GenOutputFig, 'Mwh')
            zlabel(app.GenOutputFig, 'Z')
            app.GenOutputFig.XTick = [];
            app.PriceFig = uiaxes(app.UIFigure);
            title(app.PriceFig, 'Price')
            xlabel(app.PriceFig, 'Time (h)')
            ylabel(app.PriceFig, 'USD/Mwh')
            zlabel(app.PriceFig, 'Z')
            app.PriceFig.XTick = [];
            app.PriceFig.YTick = [];
            app.EnerLevFig = uiaxes(app.UIFigure);
            title(app.EnerLevFig, 'Energy Storage Level')
            xlabel(app.EnerLevFig, 'Time (h)')
            ylabel(app.EnerLevFig, 'Energy Storage (Mwh)')
            zlabel(app.EnerLevFig, 'Z')
            app.EnerLevFig.XTick = [];
            app.ModelResultsLabel = uilabel(app.UIFigure);
            app.ModelResultsLabel.Text = 'Model Results';
            app.TotalOutputRevenueEditFieldLabel = uilabel(app.UIFigure);
            app.TotalOutputRevenueEditFieldLabel.Text = 'Total Output Revenue';
            app.TotalOutputRevenueEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalOutputRevenueEditField.ValueDisplayFormat = '%e';
            app.TotalOutputRevenueEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outputapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end